classdef imat_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        dimEditFieldLabel        matlab.ui.control.Label
        dimEditField             matlab.ui.control.NumericEditField
        tauEditFieldLabel        matlab.ui.control.Label
        tauEditField             matlab.ui.control.NumericEditField
        rcondEditFieldLabel      matlab.ui.control.Label
        rcondEditField           matlab.ui.control.NumericEditField
        omegaEditFieldLabel      matlab.ui.control.Label
        omegaEditField           matlab.ui.control.NumericEditField
        GenerateButton           matlab.ui.control.Button
        MatrixtypeDropDownLabel  matlab.ui.control.Label
        MatrixtypeDropDown       matlab.ui.control.DropDown
        tolEditFieldLabel        matlab.ui.control.Label
        tolEditField             matlab.ui.control.NumericEditField
        nmaxEditFieldLabel       matlab.ui.control.Label
        nmaxEditField            matlab.ui.control.NumericEditField
        mEditFieldLabel          matlab.ui.control.Label
        mEditField               matlab.ui.control.NumericEditField
        RightPanel               matlab.ui.container.Panel
        UIAxes                   matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            
            n = app.dimEditField.Value;
            tau = app.tauEditField.Value;
            rcond = app.tauEditField.Value;
            
            nmax = app.nmaxEditField.Value;
            tol = app.tolEditField.Value;
            omega = app.omegaEditField.Value;
            m = 2;
            i = 1;
            
            matrix_type = app.MatrixtypeDropDown.Value;
            % matrix 1
            if strcmp(matrix_type, 'Diagonally dominant matrix sparse')
                m = app.mEditField.Value;
                A = rand(n);
                A = A - diag(diag(A));
                s = abs(A)*(ones(n,1));
                dA = A + m*diag(s);
                A = sparse(dA); % makes a full matrix sparse
                figure(1);
            
            % matrix 2
            elseif strcmp(matrix_type, 'Positive symmetric definite matrix')
                A = sprandsym(n, 1, rcond, 1);
                figure(2)
                
            % matrix 3
            elseif strcmp(matrix_type, 'Positive and sparse symmetric definite matrix')
                A = sprandsym(n, tau, rcond, 1); % random symmetric sparse (with the last specific parameter being positive definite)
                figure(3)
            
            % matrix 4
            elseif strcmp(matrix_type, 'Poisson''s matrix')
                A = gallery('poisson', n); % Block tridiagonal matrix from Poisson's equation (sparse)
                figure(4)
            end


            A = sprandsym(n, tau, rcond, 1);
            sol = ones(n,1);
            b = A*sol;
            x0 = zeros(n,1);
            
            % Matlab gradient conjugate
            [x, flag, relres, iter, resvec] = pcg (A, b, tol, nmax, [], [], x0); % we can also not insert tol and nmax because they are set by default
            error1(i) = norm(sol-x)/norm(sol); % relative error
            x1(i) = {x};
            iter1(i) = iter;
            resvec1(i) = {resvec};
            
            %figure(i);
            spy(A);
            
            semilogy(app.UIAxes, resvec1{1}, '--g', 'LineWidth',5);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {541, 541};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.dimEditFieldLabel = uilabel(app.LeftPanel);
            app.dimEditFieldLabel.Text = 'dim';
            app.dimEditField = uieditfield(app.LeftPanel, 'numeric');
            app.dimEditField.Value = 100;
            app.tauEditFieldLabel = uilabel(app.LeftPanel);
            app.tauEditFieldLabel.Text = 'tau';
            app.tauEditField = uieditfield(app.LeftPanel, 'numeric');
            app.tauEditField.Limits = [0.01 1];
            app.tauEditField.Value = 0.05;
            app.rcondEditFieldLabel = uilabel(app.LeftPanel);
            app.rcondEditFieldLabel.Text = 'rcond';
            app.rcondEditField = uieditfield(app.LeftPanel, 'numeric');
            app.rcondEditField.Limits = [0.01 1];
            app.rcondEditField.Value = 0.01;
            app.omegaEditFieldLabel = uilabel(app.LeftPanel);
            app.omegaEditFieldLabel.Text = 'omega';
            app.omegaEditField = uieditfield(app.LeftPanel, 'numeric');
            app.omegaEditField.Value = 0.5;
            app.GenerateButton = uibutton(app.LeftPanel, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.MatrixtypeDropDownLabel = uilabel(app.LeftPanel);
            app.MatrixtypeDropDownLabel.Text = 'Matrix type';
            app.MatrixtypeDropDown = uidropdown(app.LeftPanel);
            app.MatrixtypeDropDown.Items = {'Diagonally dominant matrix sparse', 'Positive symmetric definite matrix', 'Positive and sparse symmetric definite matrix', 'Poisson''s matrix'};
            app.MatrixtypeDropDown.Value = 'Diagonally dominant matrix sparse';
            app.tolEditFieldLabel = uilabel(app.LeftPanel);
            app.tolEditFieldLabel.Text = 'tol';
            app.tolEditField = uieditfield(app.LeftPanel, 'numeric');
            app.tolEditField.Value = 1e-12;
            app.nmaxEditFieldLabel = uilabel(app.LeftPanel);
            app.nmaxEditFieldLabel.Text = 'nmax';
            app.nmaxEditField = uieditfield(app.LeftPanel, 'numeric');
            app.nmaxEditField.Limits = [0 Inf];
            app.nmaxEditField.Value = 200;
            app.mEditFieldLabel = uilabel(app.LeftPanel);
            app.mEditFieldLabel.Text = 'm';
            app.mEditField = uieditfield(app.LeftPanel, 'numeric');
            app.mEditField.Value = 2;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = imat_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end